classdef cordouan_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Cordouan                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DataInputandOutputTab         matlab.ui.container.Tab
        InputFileDirectoryEditFieldLabel  matlab.ui.control.Label
        InputFileDirectoryEditField   matlab.ui.control.EditField
        OutputFileDirectoryEditFieldLabel  matlab.ui.control.Label
        OutputFileDirectoryEditField  matlab.ui.control.EditField
        SampleEditFieldLabel          matlab.ui.control.Label
        SampleEditField               matlab.ui.control.EditField
        ReferenceEditFieldLabel       matlab.ui.control.Label
        ReferenceEditField            matlab.ui.control.EditField
        OutputFilenameEditFieldLabel  matlab.ui.control.Label
        OutputFilenameEditField       matlab.ui.control.EditField
        TD_plot                       matlab.ui.control.UIAxes
        Power_plot                    matlab.ui.control.UIAxes
        WindowingDropDownLabel        matlab.ui.control.Label
        WindowingDropDown             matlab.ui.control.DropDown
        WindowWidthpsEditFieldLabel   matlab.ui.control.Label
        WindowWidthpsEditField        matlab.ui.control.NumericEditField
        LOADPLOTButton                matlab.ui.control.Button
        BrowseButton_InputDir         matlab.ui.control.Button
        BrowseButton_OutputDir        matlab.ui.control.Button
        vButton_SampleFile            matlab.ui.control.Button
        vButton_ReferenceFile         matlab.ui.control.Button
        ZeroPaddingLabel              matlab.ui.control.Label
        ZeroPadding                   matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        SimulationParametersTab       matlab.ui.container.Tab
        DoNotPressButton              matlab.ui.control.Button
        TransferFunctionBuilderTab    matlab.ui.container.Tab
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % What to do when app starts
        end

        % Callback function: InputFileDirectoryEditField, 
        % InputFileDirectoryEditField, LOADPLOTButton
        function LOADPLOTButtonPushed(app, event)
            %% What to do when button is pushed for plot and load
            app.TD_plot.cla; % Clears TD plot
            app.Power_plot.cla; % Clears power spectrum plot
            % Import data
            input_sample = strcat(app.InputFileDirectoryEditField.Value,'\',app.SampleEditField.Value)
            [t_sample_TD, A_sample_TD] = Import_for_timfiles(input_sample);
            input_reference = strcat(app.InputFileDirectoryEditField.Value,'\',app.ReferenceEditField.Value)
            [t_ref_TD, A_ref_TD] = Import_for_timfiles(input_reference);
            
            % Window data based on drop-down options
            if strcmp(app.WindowingDropDown.Value,'Gaussian')
                type = 'gauss';
            elseif strcmp(app.WindowingDropDown.Value,'Square')
                type = 'square';
            else
                type = 'none';
            end
                
            A_sample_TD_windowed = TD_window(t_sample_TD,A_sample_TD,type,app.WindowWidthpsEditField.Value);
            A_ref_TD_windowed = TD_window(t_ref_TD,A_ref_TD,type,app.WindowWidthpsEditField.Value);
            
            % Plot resulting data
            hold(app.TD_plot,'on');
            plot(app.TD_plot,t_sample_TD,A_sample_TD_windowed);
            plot(app.TD_plot,t_ref_TD,A_ref_TD_windowed);
            hold(app.TD_plot,'off');
            legend(app.TD_plot,'Sample','Reference');
            
            %% Fourier Transform and Calculate Power Spectrum of Windowed Data
            N = 2^(app.ZeroPadding.Value);
            [freq_sample, spec_sample] = fft_func(t_sample_TD,A_sample_TD,N,type,app.WindowWidthpsEditField.Value);
            [freq_ref, spec_ref] = fft_func(t_ref_TD,A_ref_TD,N,type,app.WindowWidthpsEditField.Value);
            
            power_sample = abs(spec_sample);
            power_ref = abs(spec_ref);
            
            % Plot power spectra
            hold(app.Power_plot,'on');
            plot(app.Power_plot,freq_sample,power_sample);
            plot(app.Power_plot,freq_ref,power_ref);
            hold(app.Power_plot,'off');
            legend(app.Power_plot,'Sample','Reference');
            
            
        end

        % Button pushed function: DoNotPressButton
        function DoNotPressButtonPushed(app, event)
            %% terminate code
            warning = 'Why did you press?'
        end

        % Button pushed function: BrowseButton_InputDir
        function BrowseButton_InputDirPushed(app, event)
            path_input = uigetdir;
            app.InputFileDirectoryEditField.Value = path_input;
        end

        % Button pushed function: BrowseButton_OutputDir
        function BrowseButton_OutputDirPushed(app, event)
            path_output = uigetdir;
            app.OutputFileDirectoryEditField.Value = path_output;
        end

        % Button pushed function: vButton_SampleFile
        function vButton_SampleFilePushed(app, event)
            input_dir = app.InputFileDirectoryEditField.Value;
            sample_input = uigetfile(strcat(input_dir,'/*.tim'));
            app.SampleEditField.Value = sample_input;
        end

        % Button pushed function: vButton_ReferenceFile
        function vButton_ReferenceFilePushed(app, event)
            input_dir = app.InputFileDirectoryEditField.Value;
            ref_input = uigetfile(strcat(input_dir,'/*.tim'));
            app.ReferenceEditField.Value = ref_input;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Cordouan = uifigure;
            app.Cordouan.Name = 'Cordouan - Interactive THz Data Processing';
            app.TabGroup = uitabgroup(app.Cordouan);
            app.DataInputandOutputTab = uitab(app.TabGroup);
            app.DataInputandOutputTab.Title = 'Data Input and Output';
            app.InputFileDirectoryEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.InputFileDirectoryEditFieldLabel.Text = 'Input File Directory';
            app.InputFileDirectoryEditField = uieditfield(app.DataInputandOutputTab, 'text');
            app.InputFileDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @LOADPLOTButtonPushed, true);
            app.InputFileDirectoryEditField.ValueChangingFcn = createCallbackFcn(app, @LOADPLOTButtonPushed, true);
            app.InputFileDirectoryEditField.Value = 'C:\Users\jacob\Documents\Schmuttenmaer Group\Datas\2019_10_31_TDS';
            app.OutputFileDirectoryEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.OutputFileDirectoryEditFieldLabel.Text = 'Output File Directory';
            app.OutputFileDirectoryEditField = uieditfield(app.DataInputandOutputTab, 'text');
            app.OutputFileDirectoryEditField.Value = 'C:\Users\jacob\Documents\Schmuttenmaer Group\Datas\2019_10_31_TDS\output';
            app.SampleEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.SampleEditFieldLabel.Text = 'Sample';
            app.SampleEditField = uieditfield(app.DataInputandOutputTab, 'text');
            app.SampleEditField.Value = 'Oct31_123.tim';
            app.ReferenceEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.ReferenceEditFieldLabel.Text = 'Reference';
            app.ReferenceEditField = uieditfield(app.DataInputandOutputTab, 'text');
            app.ReferenceEditField.Value = 'Oct31_122.tim';
            app.OutputFilenameEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.OutputFilenameEditFieldLabel.Text = 'Output Filename';
            app.OutputFilenameEditField = uieditfield(app.DataInputandOutputTab, 'text');
            app.OutputFilenameEditField.Value = 'test_output.txt';
            app.TD_plot = uiaxes(app.DataInputandOutputTab);
            title(app.TD_plot, 'Time Domain')
            xlabel(app.TD_plot, 't_{probe} (ps)')
            ylabel(app.TD_plot, 'Amplitude (a.u.)')
            app.Power_plot = uiaxes(app.DataInputandOutputTab);
            title(app.Power_plot, 'Power Spectrum')
            xlabel(app.Power_plot, 'Frequency (THz)')
            ylabel(app.Power_plot, 'Power (a.u.)')
            app.Power_plot.XLim = [0.1 10];
            app.Power_plot.YScale = 'log';
            app.WindowingDropDownLabel = uilabel(app.DataInputandOutputTab);
            app.WindowingDropDownLabel.Text = 'Windowing';
            app.WindowingDropDown = uidropdown(app.DataInputandOutputTab);
            app.WindowingDropDown.Items = {'None', 'Gaussian', 'Square'};
            app.WindowingDropDown.Value = 'None';
            app.WindowWidthpsEditFieldLabel = uilabel(app.DataInputandOutputTab);
            app.WindowWidthpsEditFieldLabel.Text = 'Window Width (ps)';
            app.WindowWidthpsEditField = uieditfield(app.DataInputandOutputTab, 'numeric');
            app.WindowWidthpsEditField.Value = 6.5;
            app.LOADPLOTButton = uibutton(app.DataInputandOutputTab, 'push');
            app.LOADPLOTButton.ButtonPushedFcn = createCallbackFcn(app, @LOADPLOTButtonPushed, true);
            app.LOADPLOTButton.Text = 'LOAD & PLOT';
            app.BrowseButton_InputDir = uibutton(app.DataInputandOutputTab, 'push');
            app.BrowseButton_InputDir.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_InputDirPushed, true);
            app.BrowseButton_InputDir.Text = 'Browse';
            app.BrowseButton_OutputDir = uibutton(app.DataInputandOutputTab, 'push');
            app.BrowseButton_OutputDir.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_OutputDirPushed, true);
            app.BrowseButton_OutputDir.Text = 'Browse';
            app.vButton_SampleFile = uibutton(app.DataInputandOutputTab, 'push');
            app.vButton_SampleFile.ButtonPushedFcn = createCallbackFcn(app, @vButton_SampleFilePushed, true);
            app.vButton_SampleFile.Text = 'v';
            app.vButton_ReferenceFile = uibutton(app.DataInputandOutputTab, 'push');
            app.vButton_ReferenceFile.ButtonPushedFcn = createCallbackFcn(app, @vButton_ReferenceFilePushed, true);
            app.vButton_ReferenceFile.Text = 'v';
            app.ZeroPaddingLabel = uilabel(app.DataInputandOutputTab);
            app.ZeroPaddingLabel.Text = 'Zero Padding';
            app.ZeroPadding = uieditfield(app.DataInputandOutputTab, 'numeric');
            app.ZeroPadding.Value = 18;
            app.Label = uilabel(app.DataInputandOutputTab);
            app.Label.Text = '2';
            app.SimulationParametersTab = uitab(app.TabGroup);
            app.SimulationParametersTab.Title = 'Simulation Parameters';
            app.DoNotPressButton = uibutton(app.SimulationParametersTab, 'push');
            app.DoNotPressButton.ButtonPushedFcn = createCallbackFcn(app, @DoNotPressButtonPushed, true);
            app.DoNotPressButton.Text = 'Do Not Press';
            app.TransferFunctionBuilderTab = uitab(app.TabGroup);
            app.TransferFunctionBuilderTab.Title = 'Transfer Function Builder';
        end
    end

    methods (Access = public)

        % Construct app
        function app = cordouan_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Cordouan)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Cordouan)
        end
    end
end